classdef TinyPermViewDetail < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Slider                      matlab.ui.control.Slider
        SampleIDDropDown            matlab.ui.control.DropDown
        SampleIDDropDownLabel       matlab.ui.control.Label
        MeasurementIDDropDown       matlab.ui.control.DropDown
        MeasurementIDDropDownLabel  matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        FieldPermTextArea           matlab.ui.control.TextArea
        FieldPermTextAreaLabel      matlab.ui.control.Label
        BarPressureTextArea         matlab.ui.control.TextArea
        BarPressureTextAreaLabel    matlab.ui.control.Label
        LongitudeTextArea           matlab.ui.control.TextArea
        LongitudeTextAreaLabel      matlab.ui.control.Label
        LatitudeTextArea            matlab.ui.control.TextArea
        LatitudeTextAreaLabel       matlab.ui.control.Label
        DateTextArea                matlab.ui.control.TextArea
        DateTextAreaLabel           matlab.ui.control.Label
        PermeabilityTextArea        matlab.ui.control.TextArea
        PermeabilityTextAreaLabel   matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function makeboxplot(app)
            load('Detail.mat')
            x = [];
            g = [];
            
            fieldss = fieldnames(Detail);
            fieldss = fieldss(5:end); %select only from field work
            for n = 1:length(fieldss)
                Name = char(fieldss(n));
                perm = [];
                pointss = fieldnames(Detail.(Name));
                for m = 1:length(pointss)
                    try
                        point = char(pointss(m));
                        if Detail.(Name).(point).Perm*9.869233e-16 <20
                        x1 = Detail.(Name).(point).Perm*9.869233e-16;
                        x = [x; x1]; %permeability of each sample for plot
                        x1
                        perm = [perm; x1]; %permeability of each sample for detail
                        g = [g; Name];
                        end
                    catch
                        [Name point]
                    end
                end
                Detail.(Name).Perm = perm;

            end

            boxplot(app.UIAxes3,x,g,'Color',[0.7 0.7 0.7]);
            hold(app.UIAxes3,"on")


            % overlay the scatter plots
            corr = 1;
            for n=1:length(fieldss)
                Name = char(fieldss(n));
                hs = scatter(app.UIAxes3,ones(size(Detail.(Name).Perm)) + n-corr, Detail.(Name).Perm,"filled",'jitter','on','JitterAmount',0.1,'MarkerFaceAlpha', 0.8);
                FN = strrep(fieldnames(Detail.(Name)), '_', '-');
                row = dataTipTextRow('ID',FN);
                hs.DataTipTemplate.DataTipRows(1) = row; %maybe check this one
                row2 = dataTipTextRow('Perm',(Detail.(Name).Perm));
                hs.DataTipTemplate.DataTipRows(2) = row2; %maybe check this one
            end

            ylabel(app.UIAxes3,'Permeability [m^2]','Color','w')
            app.UIAxes3.YScale = 'log';
            save("Detail.mat",'Detail','-mat')
        end

        function nextdd(app,src,event,Detail,j)
            app.MeasurementIDDropDown.Items = fieldnames(Detail.(src.Value));
            ID = app.MeasurementIDDropDown.Value;
            IDS = app.SampleIDDropDown.Value;
            save('ID.mat','ID')
            save('IDS.mat','IDS')
            dotheplots(app,ID,IDS,j)
        end

        function useID(app,src,event,Detail,j)
            ID = app.MeasurementIDDropDown.Value;
            IDS = app.SampleIDDropDown.Value;
            save('ID.mat','ID')
            save('IDS.mat','IDS')
            dotheplots(app,ID,IDS,j)
        end

        function dotheplots(app,ID,IDS,j)
            load('Detail.mat')
            xlabel(app.UIAxes2,'Time [s]')
            yyaxis(app.UIAxes2, 'left')
            timesteps = Detail.(IDS).(ID).Time(2:end) - Detail.(IDS).(ID).Time(1:end-1);
            sumdiff = movsum(abs(diff(timesteps)),[0 10]) %sum of the next 10 differentials
            flats = timesteps(2:end).*(sumdiff==0);
            [~,t80] = max(flats);
            %when the average of the derivative for the next time step is 0
            %what is the first highest timestep for which this is true?
            % HOW TO FIND WHEN INTRERPOLATION STARTS: because of how the
            % data is colected, interpolaion can be detected when the
            % timestep increase and become conststant. When calculating the
            % timestep and visualising them, we can see for some data sets
            % that the highest timestep, does not necessarily corespond to
            % the start of interpolation. same for the first period of
            % constant timestep. Therefore a combination of the two aspects
            % must be met for interpolation initiatin to be detected. This
            % is done by first finding all constant timesteps area (at last
            % 10 consecutive, by taking the derivative of the timestep and
            % doing the moving cummulative sum for the next 10 points. Then
            % the index for the timesteps that satisfy that requirements
            % are compared as to find the first highest timestep of the
            % periods of constant time step and labeling that the begining
            % of interpolation.

            plot(app.UIAxes2,Detail.(IDS).(ID).Time(1:t80)*0.001,Detail.(IDS).(ID).Pressure(1:t80),Detail.(IDS).(ID).Time(t80:end)*0.001,Detail.(IDS).(ID).Pressure(t80:end),'--',"LineWidth",1)
            ylabel(app.UIAxes2,'Pressure [kPa]')
            set(app.UIAxes2, 'YDir','reverse')

            yyaxis(app.UIAxes2, 'right')
            plot(app.UIAxes2,Detail.(IDS).(ID).Time*0.001,Detail.(IDS).(ID).Stroke,"LineWidth",1)
            ylabel(app.UIAxes2,'Stroke')


            xlabel(app.UIAxes2_2,'Time [s]')
            % k = floor(length(Detail.(IDS).(ID).Time)*j/100)
            % P_down = Detail.(IDS).(ID).Stroke < 0.5*max(Detail.(IDS).(ID).Stroke);
            % [~,k1] = max(P_down);
            [~,k1] = max(abs(Detail.(IDS).(ID).Pressure));
            k1 = k1 - 10;
            t_5 = Detail.(IDS).(ID).Time*0.001 > Detail.(IDS).(ID).Time(k1)*0.001 + j;
            [mm,k2] = max(t_5);
            if mm == 0 
                k2 = length(Detail.(IDS).(ID).Time);
            end
            yyaxis(app.UIAxes2_2, 'left')
            plot(app.UIAxes2_2,Detail.(IDS).(ID).Time(k1:k2)*0.001,Detail.(IDS).(ID).Pressure(k1:k2),"LineWidth",1)
            ylabel(app.UIAxes2_2,'Pressure [kPa]')
            set(app.UIAxes2_2, 'YDir','reverse')

            % yyaxis(app.UIAxes2_2, 'right')
            % plot(app.UIAxes2_2,Detail.(IDS).(ID).Time(k1:k2)*0.001,Detail.(IDS).(ID).Stroke(k1:k2),"LineWidth",1)
            % ylabel(app.UIAxes2_2,'Stroke')

            app.FieldPermTextArea.Value = [char(num2str(Detail.(IDS).(ID).Perm)) ' microD'];
            app.BarPressureTextArea.Value = char(num2str(Detail.(IDS).(ID).BarometricPressure));
            app.LongitudeTextArea.Value = char(num2str(Detail.(IDS).(ID).Longitude));
            app.LatitudeTextArea.Value = char(num2str(Detail.(IDS).(ID).Latitude));
            app.DateTextArea.Value = char(Detail.(IDS).(ID).Date);
            app.PermeabilityTextArea.Value = [char(num2str(Detail.(IDS).(ID).Perm*9.869233e-16)) ' m2'];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % getdata(app)
            makeboxplot(app)
            load('Detail.mat')
            j = app.Slider.Value;
            names = fields(Detail);
            app.SampleIDDropDown.Items = names(5:end-1);
            app.SampleIDDropDown.ItemsData = names(5:end-1);
            IDS = app.SampleIDDropDown.Value;
            app.MeasurementIDDropDown.Items = fieldnames(Detail.(IDS));
            ID = app.MeasurementIDDropDown.Value;
            dotheplots(app,ID,IDS,j)
            save('ID.mat','ID','IDS')
            app.SampleIDDropDown.ValueChangedFcn = @(src,event)nextdd(app,src,event,Detail,j);
            app.MeasurementIDDropDown.ValueChangedFcn = @(src,event)useID(app,src,event,Detail,j);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            j = event.Value;
            load('ID.mat')
            load('IDS.mat')
            dotheplots(app,ID,IDS,j)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.PermeabilityTextAreaLabel = uilabel(app.UIFigure);
            app.PermeabilityTextAreaLabel.Text = 'Permeability';
            app.PermeabilityTextArea = uitextarea(app.UIFigure);
            app.DateTextAreaLabel = uilabel(app.UIFigure);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.UIFigure);
            app.LatitudeTextAreaLabel = uilabel(app.UIFigure);
            app.LatitudeTextAreaLabel.Text = 'Latitude';
            app.LatitudeTextArea = uitextarea(app.UIFigure);
            app.LongitudeTextAreaLabel = uilabel(app.UIFigure);
            app.LongitudeTextAreaLabel.Text = 'Longitude';
            app.LongitudeTextArea = uitextarea(app.UIFigure);
            app.BarPressureTextAreaLabel = uilabel(app.UIFigure);
            app.BarPressureTextAreaLabel.Text = 'Bar. Pressure';
            app.BarPressureTextArea = uitextarea(app.UIFigure);
            app.FieldPermTextAreaLabel = uilabel(app.UIFigure);
            app.FieldPermTextAreaLabel.Text = 'Field Perm';
            app.FieldPermTextArea = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'TinyPermLogo.svg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'lavapub_logo.png';
            app.MeasurementIDDropDownLabel = uilabel(app.UIFigure);
            app.MeasurementIDDropDownLabel.Text = 'Measurement ID';
            app.MeasurementIDDropDown = uidropdown(app.UIFigure);
            app.SampleIDDropDownLabel = uilabel(app.UIFigure);
            app.SampleIDDropDownLabel.Text = 'Sample ID';
            app.SampleIDDropDown = uidropdown(app.UIFigure);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 80];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 80;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TinyPermViewDetail

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end